classdef Arm_Robot < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure          matlab.ui.Figure
        SimulationButton  matlab.ui.control.StateButton
        L1EditField       matlab.ui.control.EditField
        L1EditFieldLabel  matlab.ui.control.Label
        L2EditField       matlab.ui.control.EditField
        L2EditFieldLabel  matlab.ui.control.Label
        UIAxes            matlab.ui.control.UIAxes
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button down function: UIAxes
        function UIAxesButtonDown(app, event)
      function startTimer(app)
            app.Timer = timer('ExecutionMode', 'fixedRate', 'Period', 0.1, ...
                'TimerFcn', @(~,~) updateAngles(app));
            start(app.Timer);
        end

        function updateAngles(app)
            % Update angles
            app.Theta1Value = mod(app.Theta1Value + 1, 90); % Increment theta1
            app.Theta2Value = mod(app.Theta2Value + 1, 90) + 90; % Increment theta2

            % Call the simulate function to update the plot
            simulateArm(app);
        end    
hold(app.UIAxes,'off');
l1= app.L1EditField.value;
l2= app.L2EditField.Value;
theta1=linspace(0,90,1000);
theta2=linspace(0,180,1000);

x1 = l1*cosd(theta1);
y1= l1*sind(theta1);
x2= x1+l2*cosd(theta2);
y2= y1+l2*sind(theta2);

p1 = plot(app,uiaxes,[0,x1(1)],[0,y1(1)]);
hold(app.UIAxes,'on');
p2= plot(app.UIAxes,[x1(1),x2(1)],[y1(1),y2(1)]);
hold on;
xlim (app.UIAxes,[-l1*2,l1*2]);
ylim (app.UIAxes,[-l1*2,l1*2]);
plot(app.UIAxes,x2,y2);
for i=1:10:length(theta1)
  delete p1;
  delete p2;
  p1 = plot(app.UIAxes,[0,x1(i)],[0,y1(i)],'r');
  p2= plot(app.UIAxes,[x1(i),x2(i)],[y1(i),y2(i)],'b');
drawnow
end


        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes.ButtonDownFcn = createCallbackFcn(app, @UIAxesButtonDown, true);
            app.L2EditFieldLabel = uilabel(app.UIFigure);
            app.L2EditFieldLabel.Text = 'L2';
            app.L2EditField = uieditfield(app.UIFigure, 'text');
            app.L2EditField.Value = '10';
            app.L1EditFieldLabel = uilabel(app.UIFigure);
            app.L1EditFieldLabel.Text = 'L1';
            app.L1EditField = uieditfield(app.UIFigure, 'text');
            app.L1EditField.Value = '10';
            app.SimulationButton = uibutton(app.UIFigure, 'state');
            app.SimulationButton.Text = 'Simulation';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Arm_Robot

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end